classdef Template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_5                matlab.ui.control.UIAxes
        UIAxes_6                matlab.ui.control.UIAxes
        UIAxes_7                matlab.ui.control.UIAxes
        UIAxes_8                matlab.ui.control.UIAxes
        UIAxes_9                matlab.ui.control.UIAxes
        ETATDELAUISITIONPanel   matlab.ui.container.Panel
        EditField               matlab.ui.control.EditField
        INTERFACEGRAPHIQUECONSTRUCTIONDELABASEDEDONNELabel  matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        AquisitionTab           matlab.ui.container.Tab
        InfoButton              matlab.ui.control.Button
        AffichageTab            matlab.ui.container.Tab
        InfoButtonAffichage     matlab.ui.control.Button
        BasededonneTab          matlab.ui.container.Tab
        MouvementDropDownLabel  matlab.ui.control.Label
        MouvementDropDown       matlab.ui.control.DropDown
        SAUVEGARDERButton       matlab.ui.control.StateButton
        InfoButton_3            matlab.ui.control.Button
    end

    
    methods (Access = private)
        
   function infoButtonPushed(app, event)
    % code à exécuter lorsque le bouton "Info" est cliqué
    message = 'Ceci est un message d''information ou d''aide.NTI BAHLOULAAAA';
    title = 'Aide';
    msgbox(message, title, 'help');
   end
        
        function InfoButtonAffichagePushed(app, event)
    % code à exécuter lorsque le bouton "Info" est cliqué
    message = 'test 2';
    title = 'Aide';
    msgbox(message, title, 'help');
   end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            app.InfoButton.Tag = 'info'; 
            app.InfoButton.Text = 'Info';
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
        end

        % Button pushed function: InfoButtonAffichage
        function InfoButtonAffichagePushed2(app, event)
            app.InfoButtonAffichage.Tag = 'info'; 
            app.InfoButtonAffichage.Text = 'Info';
            app.InfoButtonAffichage.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'signal EMG (canal 1)')
            xlabel(app.UIAxes, 'Temps(s)')
            ylabel(app.UIAxes, 'Amplitude(V)')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'Temps(s)')
            ylabel(app.UIAxes_3, 'Amplitude(V)')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'Temps(s)')
            ylabel(app.UIAxes_4, 'Amplitude(V)')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'Temps(s)')
            ylabel(app.UIAxes_5, 'Amplitude(V)')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'Temps(s)')
            ylabel(app.UIAxes_6, 'Amplitude(V)')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'Temps(s)')
            ylabel(app.UIAxes_7, 'Amplitude(V)')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'Temps(s)')
            ylabel(app.UIAxes_8, 'Amplitude(V)')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, 'Temps(s)')
            ylabel(app.UIAxes_9, 'Amplitude(V)')
            app.ETATDELAUISITIONPanel = uipanel(app.UIFigure);
            app.ETATDELAUISITIONPanel.Title = 'ETAT DE LAUISITION';
            app.EditField = uieditfield(app.ETATDELAUISITIONPanel, 'text');
            app.INTERFACEGRAPHIQUECONSTRUCTIONDELABASEDEDONNELabel = uilabel(app.UIFigure);
            app.INTERFACEGRAPHIQUECONSTRUCTIONDELABASEDEDONNELabel.Text = 'INTERFACE GRAPHIQUE (CONSTRUCTION DE LA BASE DE DONNÉE)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AquisitionTab = uitab(app.TabGroup);
            app.AquisitionTab.Title = 'Aquisition';
            app.InfoButton = uibutton(app.AquisitionTab, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = '?';
            app.AffichageTab = uitab(app.TabGroup);
            app.AffichageTab.Title = 'Affichage';
            app.InfoButtonAffichage = uibutton(app.AffichageTab, 'push');
            app.InfoButtonAffichage.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonAffichagePushed2, true);
            app.InfoButtonAffichage.Text = '?';
            app.BasededonneTab = uitab(app.TabGroup);
            app.BasededonneTab.Title = 'Base de donnée';
            app.MouvementDropDownLabel = uilabel(app.BasededonneTab);
            app.MouvementDropDownLabel.Text = 'Mouvement';
            app.MouvementDropDown = uidropdown(app.BasededonneTab);
            app.MouvementDropDown.Items = {'mvt 1', 'mvt2', 'mv3', 'mvt4'};
            app.MouvementDropDown.Value = 'mvt 1';
            app.SAUVEGARDERButton = uibutton(app.BasededonneTab, 'state');
            app.SAUVEGARDERButton.Text = 'SAUVEGARDER';
            app.InfoButton_3 = uibutton(app.BasededonneTab, 'push');
            app.InfoButton_3.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Template

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end